classdef LPRS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LicensePlateStatusEditField    matlab.ui.control.EditField
        LicensePlateStatusEditFieldLabel  matlab.ui.control.Label
        LicensePlateNumberEditField    matlab.ui.control.EditField
        LicensePlateNumberEditFieldLabel  matlab.ui.control.Label
        PrintStatusButton              matlab.ui.control.Button
        PrintLicensePlateNumberButton  matlab.ui.control.Button
        DetectLicensePlateButton       matlab.ui.control.Button
        LicensePlateRecognitionSystemLPRSLabel  matlab.ui.control.Label
        UploadImageButton              matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        colorImage;
        plateNumber;
        state;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            % Allow the user to select an image file
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, 'Select an image file');
            if isequal(filename,0) || isequal(pathname,0)
                disp('User canceled the operation');
                return;
            end
            
            % % Read the selected image
            app.colorImage = imread(fullfile(pathname, filename));

            % Display image and stretch to fill axes
            I = imshow(app.colorImage, 'Parent', app.UIAxes, ...
                    'XData', [1 app.UIAxes.Position(3)], ...
                    'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];              
        end

        % Button pushed function: DetectLicensePlateButton
        function DetectLicensePlateButtonPushed(app, event)
            
            % colorImage = imread("handicapSign.jpg");
            I = im2gray(app.colorImage);
            
            % Detect MSER regions.
            [mserRegions, mserConnComp] = detectMSERFeatures(I, ... 
                "RegionAreaRange",[100 1000],"ThresholdDelta",2);
            
            mserStats = regionprops(mserConnComp, "BoundingBox", "Eccentricity", ...
                "Solidity", "Extent", "Euler", "Image");
            
            % Compute the aspect ratio using bounding box data.
            bbox = vertcat(mserStats.BoundingBox);
            w = bbox(:,3);
            h = bbox(:,4);
            aspectRatio = w./h;
            
            % Threshold the data to determine which regions to remove. These thresholds
            % may need to be tuned for other images.
            filterIdx = aspectRatio' > 1.7;
            filterIdx = filterIdx | [mserStats.Eccentricity] > .995 ;
            filterIdx = filterIdx | [mserStats.Solidity] < .25;
            filterIdx = filterIdx | [mserStats.Extent] < 0.2 | [mserStats.Extent] > 0.9;
            filterIdx = filterIdx | [mserStats.EulerNumber] < -4;
            
            % Remove regions
            mserStats(filterIdx) = [];
            mserRegions(filterIdx) = [];
            
            % Get a binary image of the a region, and pad it to avoid boundary effects
            % during the stroke width computation.
            regionImage = mserStats(6).Image;
            regionImage = padarray(regionImage, [1 1]);
            
            % Compute the stroke width image.
            distanceImage = bwdist(~regionImage); 
            skeletonImage = bwmorph(regionImage, "thin", inf);
            
            strokeWidthImage = distanceImage;
            strokeWidthImage(~skeletonImage) = 0;
            
            % Compute the stroke width variation metric 
            strokeWidthValues = distanceImage(skeletonImage);   
            strokeWidthMetric = std(strokeWidthValues)/mean(strokeWidthValues);
            
            % Threshold the stroke width variation metric
            strokeWidthThreshold = 4;
            strokeWidthFilterIdx = strokeWidthMetric > strokeWidthThreshold;
            
            % Process the remaining regions
            for j = 1:numel(mserStats)
                
                regionImage = mserStats(j).Image;
                regionImage = padarray(regionImage, [1 1], 0);
                
                distanceImage = bwdist(~regionImage);
                skeletonImage = bwmorph(regionImage, "thin", inf);
                
                strokeWidthValues = distanceImage(skeletonImage);
                
                strokeWidthMetric = std(strokeWidthValues)/mean(strokeWidthValues);
                
                strokeWidthFilterIdx(j) = strokeWidthMetric > strokeWidthThreshold;
                
            end
            
            % Remove regions based on the stroke width variation
            mserRegions(strokeWidthFilterIdx) = [];
            mserStats(strokeWidthFilterIdx) = [];
            
            % Get bounding boxes for all the regions
            bboxes = vertcat(mserStats.BoundingBox);
            
            % Convert from the [x y width height] bounding box format to the [xmin ymin
            % xmax ymax] format for convenience.
            xmin = bboxes(:,1);
            ymin = bboxes(:,2);
            xmax = xmin + bboxes(:,3) - 1;
            ymax = ymin + bboxes(:,4) - 1;
            
            % Expand the bounding boxes by a small amount.
            expansionAmount = 0.02;
            xmin = (1-expansionAmount) * xmin;
            ymin = (1-expansionAmount) * ymin;
            xmax = (1+expansionAmount) * xmax;
            ymax = (1+expansionAmount) * ymax;
            
            % Clip the bounding boxes to be within the image bounds
            xmin = max(xmin, 1);
            ymin = max(ymin, 1);
            xmax = min(xmax, size(I,2));
            ymax = min(ymax, size(I,1));
            
            % Show the expanded bounding boxes
            expandedBBoxes = [xmin ymin xmax-xmin+1 ymax-ymin+1];
            IExpandedBBoxes = insertShape(app.colorImage,"rectangle",expandedBBoxes,"LineWidth",3);
            
            % Compute the overlap ratio
            overlapRatio = bboxOverlapRatio(expandedBBoxes, expandedBBoxes);
            
            % Set the overlap ratio between a bounding box and itself to zero to
            % simplify the graph representation.
            n = size(overlapRatio,1); 
            overlapRatio(1:n+1:n^2) = 0;
            
            % Create the graph
            g = graph(overlapRatio);
            
            % Find the connected text regions within the graph
            componentIndices = conncomp(g);
            
            % Merge the boxes based on the minimum and maximum dimensions.
            xmin = accumarray(componentIndices', xmin, [], @min);
            ymin = accumarray(componentIndices', ymin, [], @min);
            xmax = accumarray(componentIndices', xmax, [], @max);
            ymax = accumarray(componentIndices', ymax, [], @max);
            
            % Compose the merged bounding boxes using the [x y width height] format.
            textBBoxes = [xmin ymin xmax-xmin+1.5 ymax-ymin+1];
            
            % Remove bounding boxes that only contain one text region
            numRegionsInGroup = histcounts(componentIndices);
            textBBoxes(numRegionsInGroup == 1, :) = [];
            
            % Show the final text detection result.
            ITextRegion = insertShape(app.colorImage, "rectangle", textBBoxes,"LineWidth",3);
                      
            % Create an empty cell array to store OCR results for each region
            ocrResults = cell(size(textBBoxes, 1), 1);

            % Iterate over each bounding box
            for i = 1:size(textBBoxes, 1)
                % Extract coordinates of the current bounding box
                xmin = textBBoxes(i, 1);
                ymin = textBBoxes(i, 2);
                width = textBBoxes(i, 3);
                height = textBBoxes(i, 4);
                
                % Crop the region from the original image
                cropped_region = app.colorImage(ymin:ymin+height-1, xmin:xmin+width-1, :);
                
                % Perform OCR on the cropped region
                ocrResults{i} = ocr(cropped_region);
            end
            
            % Assuming ocrResults is the cell array containing OCR results
            licensePlateRegex = '[A-Z]{1,2}\s?\d{1,4}\s?[A-Z]?'; % Regular expression for Malaysian license plate format
            
            % Create a wait bar to track OCR progress
            waitbarOCR = waitbar(0, 'Detecting License Plate...', 'Name', 'Progress');
            
            % Loop through OCR results
            for i = 1:numel(ocrResults)
                % Update wait bar progress
                waitbar(i/numel(ocrResults), waitbarOCR, sprintf('Processing image...'));
    
                % Extract text from OCR result
                ocrText = ocrResults{i}.Text;
                % ocrText = ocrResults{i}.Text;
                
                % Search for license plate patterns using regular expression
                matches = regexp(ocrText, licensePlateRegex, 'match');
                
                % Display matched license plate numbers and cropped regions
                if ~isempty(matches)
                    disp(['License plate number(s) in Region ', num2str(i)]);
                    disp(matches);
                    
                    % Extract coordinates of the current bounding box
                    xmin = textBBoxes(i, 1);
                    ymin = textBBoxes(i, 2);
                    width = textBBoxes(i, 3);
                    height = textBBoxes(i, 4);
            
                    % Crop the region from the original image
                    cropped_region = app.colorImage(ymin:ymin+height-1, xmin:xmin+width-1, :);
                    
                    % Clear the axes
                    cla(app.UIAxes_2);

                    I = imshow(cropped_region, 'Parent', app.UIAxes_2, ...
                    'XData', [1 app.UIAxes_2.Position(3)], ...
                    'YData', [1 app.UIAxes_2.Position(4)]);
                % Set limits of axes
                app.UIAxes_2.XLim = [0 I.XData(2)];
                app.UIAxes_2.YLim = [0 I.YData(2)];


                    Numplate = ocr(cropped_region);
                    recognizedText = Numplate.Text;
                    app.plateNumber = regexp(recognizedText, '[A-Z]{2,}\s*(\d+)', 'match', 'once');
                    
                    
                    % licensePlateNumber = Numplate.Words{1};
                    stateCode = app.plateNumber(1); % Extract the first character

                    % Define state/territory codes
                    
                    states = {'Perak', 'Selangor', 'Pahang', 'Kelantan', 'Putrajaya', 'Johor', ...
                              'Kedah', 'Melaka', 'Negeri Sembilan', 'Penang', 'Perlis', ...
                              'Terengganu', 'Kuala Lumpur', 'Kuala Lumpur', 'Military', 'Singapore', 'Taxi'};
                
                    % Define state/territory codes
                    codes = {'A', 'B', 'C', 'D', 'F', 'J', 'K', 'M', 'N', 'P', 'R', 'T', 'V', 'W', 'Z', 'S', 'H'};
                    
                    % Find the index of the prefix in the codes array
                    index = find(strcmp(codes, stateCode));
                    
                    % Check if prefix is valid
                    if ~isempty(index)
                        app.state = states{index};
                    else
                        app.state = 'Unknown';
                    end
                end
            end
            % Close the wait bar after OCR process is complete
            delete(waitbarOCR);
        end

        % Button pushed function: PrintLicensePlateNumberButton
        function PrintLicensePlateNumberButtonPushed(app, event)
            app.LicensePlateNumberEditField.Value = app.plateNumber;
        end

        % Button pushed function: PrintStatusButton
        function PrintStatusButtonPushed(app, event)
            app.LicensePlateStatusEditField.Value = app.state;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Image')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'License Plate')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_2.ZTick = [];
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.LicensePlateRecognitionSystemLPRSLabel = uilabel(app.UIFigure);
            app.LicensePlateRecognitionSystemLPRSLabel.Text = 'License Plate Recognition System (LPRS)';
            app.DetectLicensePlateButton = uibutton(app.UIFigure, 'push');
            app.DetectLicensePlateButton.ButtonPushedFcn = createCallbackFcn(app, @DetectLicensePlateButtonPushed, true);
            app.DetectLicensePlateButton.Text = 'Detect License Plate';
            app.PrintLicensePlateNumberButton = uibutton(app.UIFigure, 'push');
            app.PrintLicensePlateNumberButton.ButtonPushedFcn = createCallbackFcn(app, @PrintLicensePlateNumberButtonPushed, true);
            app.PrintLicensePlateNumberButton.Text = 'Print License Plate Number';
            app.PrintStatusButton = uibutton(app.UIFigure, 'push');
            app.PrintStatusButton.ButtonPushedFcn = createCallbackFcn(app, @PrintStatusButtonPushed, true);
            app.PrintStatusButton.Text = 'Print Status';
            app.LicensePlateNumberEditFieldLabel = uilabel(app.UIFigure);
            app.LicensePlateNumberEditFieldLabel.Text = 'License Plate Number';
            app.LicensePlateNumberEditField = uieditfield(app.UIFigure, 'text');
            app.LicensePlateStatusEditFieldLabel = uilabel(app.UIFigure);
            app.LicensePlateStatusEditFieldLabel.Text = 'License Plate Status';
            app.LicensePlateStatusEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LPRS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end